classdef Abschlussprojekt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculateButton                matlab.ui.control.Button
        ReaktorPanel                   matlab.ui.container.Panel
        N_elementsEditField            matlab.ui.control.NumericEditField
        rmN_rmelementsLabel            matlab.ui.control.Label
        T_wallEditField                matlab.ui.control.NumericEditField
        rmT_wallrmKEditFieldLabel      matlab.ui.control.Label
        T_inEditField                  matlab.ui.control.NumericEditField
        rmT_rminrmKEditFieldLabel      matlab.ui.control.Label
        u_inEditField                  matlab.ui.control.NumericEditField
        rmu_inrmms1EditFieldLabel      matlab.ui.control.Label
        p_inEditField                  matlab.ui.control.NumericEditField
        rmp_rminrmbarLabel             matlab.ui.control.Label
        StoffmengenanteilePanel        matlab.ui.container.Panel
        N2EditField                    matlab.ui.control.NumericEditField
        rmN_2EditFieldLabel            matlab.ui.control.Label
        CO2EditField                   matlab.ui.control.NumericEditField
        rmCO_2EditFieldLabel           matlab.ui.control.Label
        COEditField                    matlab.ui.control.NumericEditField
        rmCOEditFieldLabel             matlab.ui.control.Label
        H2EditField                    matlab.ui.control.NumericEditField
        rmH_2EditFieldLabel            matlab.ui.control.Label
        H2OEditField                   matlab.ui.control.NumericEditField
        rmH_2rmOEditFieldLabel         matlab.ui.control.Label
        CH4EditField                   matlab.ui.control.NumericEditField
        rmCH_4EditFieldLabel           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AxialerVerlaufTab              matlab.ui.container.Tab
        Temperaturprofil_2D_Reaktor_1  matlab.ui.control.UIAxes
        Temperatur                     matlab.ui.control.UIAxes
        Stoffmengenanteile             matlab.ui.control.UIAxes
        UmsatzFVEVerlaufTab            matlab.ui.container.Tab
        Temperaturprofil_2D_Reaktor_2  matlab.ui.control.UIAxes
        FVE_Verlauf                    matlab.ui.control.UIAxes
        Umsatz_und_Ausbeute            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clc
            % Variables from the app
            % 1:CH4; 2:H2O; 3:H2; 4:CO; 5:CO2; 6:N2; globally consistent
            x_in_CH4 = app.CH4EditField.Value;
            x_in_H20 = app.H2OEditField.Value;
            x_in_H2 = app.H2EditField.Value;
            x_in_CO = app.COEditField.Value;
            x_in_CO2 = app.CO2EditField.Value;
            x_in_N2 = app.N2EditField.Value;
            x_1 = [x_in_CH4 x_in_H20 x_in_H2 x_in_CO x_in_CO2 x_in_N2];
            
            p_tot = app.p_inEditField.Value*1e5; % Pa (Assumption: no pressure drop)
            u_in = app.u_inEditField.Value; % m/s
            T_1 = app.T_inEditField.Value; % K
            T_wall = app.T_wallEditField.Value; % K
            eta = 0.007; % Efficiency factor for catalyst (Assumption: In each catalyst unit the efficiency factor is the same)
            N_elements = app.N_elementsEditField.Value;
            
            % Run the function init_config to get all constants
            run("init_config.m")
            
            % Calculate amount of substance ni from mole fraction xi with the ideal gas equation
            c_ges = (p_tot*1e-3)/(config.const.R*T_1); % kmol/m^3
            c_1 = c_ges .* x_1; % kmol/m^3
            V_dot = u_in*3600*config.react.A; % m^3/h
            n_1 = c_1 * V_dot; % kmol/h
            
            %% Solving the 1D and 2D reactor
            % Prepare y0 for ode15 for the 2D-Reactor
            y0 = zeros([7*N_elements,1]);
            for i = 1:N_elements
                y0(7*(i-1)+1:7*i) = [n_1/N_elements T_1];
            end

            % Integration of the 1D-reactor and 2D-Reaktor
            [z_1D,y_1D] = ode15s(@func_dydz_1D_reactor,[0 config.react.L],[n_1 T_1], [],config,u_in,p_tot,T_1,T_wall,x_1,eta);
            [z_2D,y_2D] = ode15s(@func_dydz_2D_reactor,[0 config.react.L],y0, [],config,N_elements,u_in,p_tot,T_1,T_wall,x_1,eta);
            
            %% Calculation of the searched quantities for the plots
            % Calculation of the conversion and yield 
            X_CH4_1D = (y_1D(1,1)-y_1D(:,1))/y_1D(1,1); % X_CH4 = n_CH4,0-n_CH4/n_CH4,0
            Y_CO2_1D = (y_1D(:,5)-y_1D(1,5))/y_1D(1,1); % Y_CO2 = n_CO2-n_CO2,0/n_CH4,0

            X_CH4_2D = (sum(y_2D(1,1:7:7*N_elements))-sum(y_2D(:,1:7:7*N_elements)'))/...
                sum(y_2D(1,1:7:7*N_elements));
            Y_CO2_2D = (sum(y_2D(:,5:7:7*N_elements)')-sum(y_2D(1,5:7:7*N_elements)))/...
                sum(y_2D(1,1:7:7*N_elements));
            
            % Calculation of the amounts of substances 
            n_1D = y_1D(:,1:6);
            x_1D = n_1D./sum(n_1D,2);

            n_2D_avg = zeros([length(z_2D), 6]);
            for i = 1:length(z_2D)
                for j = 1:6
                    n_2D_avg(i,j) = sum(y_2D(i,j:7:7*N_elements))/N_elements;
                end
            end
            x_2D = n_2D_avg./sum(n_2D_avg,2);

            % Calculation of the Temperature
            T_1D = y_1D(:,7);

    	    T_avg_2D = zeros([length(z_2D), 1]);
            for i = 1:length(z_2D)
               T_avg_2D(i) = sum(y_2D(i,7:7:7*N_elements))/...
                   N_elements;
            end
            
            % Calculation of the radii and areas from each step
            [A_nve,r_nve] = calc_nve(z_2D,y_2D',config,N_elements,p_tot);

            %% Clear plots
            cla(app.Stoffmengenanteile)
            cla(app.Temperatur)
            cla(app.Temperaturprofil_2D_Reaktor_1)
            cla(app.Umsatz_und_Ausbeute)
            cla(app.FVE_Verlauf)
            cla(app.Temperaturprofil_2D_Reaktor_2)
            
            %% Create axial plots (1D reactor = dashed; 2D reactor = solid; globally consistent)
            tu_colors = ["#004E8A","#008877","#B90F22","#7FAB16","#611C73","#D7AC00"];

            % Plot of the amount of substance
            hold(app.Stoffmengenanteile,'on')
            for i = 1:5
                plot(app.Stoffmengenanteile,z_2D,x_2D(:,i),'color',tu_colors(i));
            end
            for i = 1:5
                plot(app.Stoffmengenanteile,z_1D,x_1D(:,i),"--",'color',tu_colors(i));
            end
            legend(app.Stoffmengenanteile,'$\rm{CH}_{4}$','$\rm{H}_{2}\rm{O}$','$\rm{H}_{2}$','$\rm{CO}$','$\rm{CO}_{2}$','','','','','','location','east','interpreter', 'latex')  
            hold(app.Stoffmengenanteile,'off')

            % Plot of the temperature (1D)
            plot(app.Temperatur,z_1D,T_1D,"--",'color',tu_colors(1));
            hold(app.Temperatur,'on')
            plot(app.Temperatur,z_2D,T_avg_2D,'color',tu_colors(1));
            hold(app.Temperatur,'off')

            % Plot of the conversion and yield 
            plot(app.Umsatz_und_Ausbeute,z_2D,X_CH4_2D*100,'color',tu_colors(3));
            hold(app.Umsatz_und_Ausbeute,'on')
            plot(app.Umsatz_und_Ausbeute,z_2D,Y_CO2_2D*100,'color',tu_colors(1));
            plot(app.Umsatz_und_Ausbeute,z_1D,X_CH4_1D*100,"--",'color',tu_colors(3));
            plot(app.Umsatz_und_Ausbeute,z_1D,Y_CO2_1D*100,"--",'color',tu_colors(1));
            hold(app.Umsatz_und_Ausbeute,'off')
            legend(app.Umsatz_und_Ausbeute,'$X_{\rm{CH}_{4}}$','$Y_{\rm{CO}_{2}}$','location','east','interpreter', 'latex')  
            
            %% Create radial plots
            % Plot of the FVE curves
            hold(app.FVE_Verlauf,'on')
            for i = 1:N_elements
                plot(app.FVE_Verlauf,z_2D,r_nve(i+1,:)*1000,'color','black');
                scatter(app.FVE_Verlauf,z_2D,r_nve(i+1,:)*1000,[],A_nve(i,:)*1000,'filled')
            end
            hold(app.FVE_Verlauf,'off')
            colormap(app.FVE_Verlauf,winter)
            c = colorbar(app.FVE_Verlauf);
            c.Label.VerticalAlignment = "top";
            c.Label.Interpreter = "latex";
            ylabel(c,"Fläche / cm^2")
           
            % Plot of the temperature (2D)
            z_plot = zeros([N_elements*2+1, length(z_2D)]);
            for i = 1:N_elements*2+1
                z_plot(i,:) = z_2D';
            end
            r_T_plot = [flip(r_nve);-r_nve(2:end,:)]*1e3;
            T_plot = [flip(y_2D(:,7:7:7*N_elements)');y_2D(:,7)';y_2D(:,7:7:7*N_elements)'];
            
            surf(app.Temperaturprofil_2D_Reaktor_1,z_plot,r_T_plot,T_plot) % Surface plot
            shading(app.Temperaturprofil_2D_Reaktor_1, 'interp') % interpolating the colormap across the lines
            view(app.Temperaturprofil_2D_Reaktor_1, 2); % Change camera line of sight
            c = colorbar(app.Temperaturprofil_2D_Reaktor_1);
            ylim(app.Temperaturprofil_2D_Reaktor_1, [-r_T_plot(1,1) r_T_plot(1,1)])
            c.Label.String = 'Temperatur / K';

            surf(app.Temperaturprofil_2D_Reaktor_2,z_plot,r_T_plot,T_plot)
            shading(app.Temperaturprofil_2D_Reaktor_2, 'interp')
            view(app.Temperaturprofil_2D_Reaktor_2, 2);
            c = colorbar(app.Temperaturprofil_2D_Reaktor_2);
            ylim(app.Temperaturprofil_2D_Reaktor_2, [-r_T_plot(1,1) r_T_plot(1,1)])
            c.Label.String = 'Temperatur / K';
            
            x_CH4_2D = x_2D(:,1); x_H2O_2D = x_2D(:,2); x_H2_2D = x_2D(:,3); x_CO_2D = x_2D(:,4); x_CO2_2D = x_2D(:,5); x_N2_2D = x_2D(:,6);
            X_CH4_2D = X_CH4_2D'; Y_CO2_2D = Y_CO2_2D';
            writetable(table(z_2D,x_CH4_2D,x_H2O_2D,x_H2_2D,x_CO_2D,x_CO2_2D,x_N2_2D,X_CH4_2D,Y_CO2_2D,T_avg_2D),'results_matlab.txt');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AxialerVerlaufTab = uitab(app.TabGroup);
            app.AxialerVerlaufTab.Title = 'Axialer Verlauf';
            app.Stoffmengenanteile = uiaxes(app.AxialerVerlaufTab);
            title(app.Stoffmengenanteile, 'Stoffmengenanteile')
            xlabel(app.Stoffmengenanteile, '\it{z} / \rm{m}')
            ylabel(app.Stoffmengenanteile, '\it{x_{i}}')
            zlabel(app.Stoffmengenanteile, 'Z')
            app.Temperatur = uiaxes(app.AxialerVerlaufTab);
            title(app.Temperatur, 'Temperatur')
            xlabel(app.Temperatur, '\it{z} / \rm{m}')
            ylabel(app.Temperatur, '\it{T} / \rm{K}')
            zlabel(app.Temperatur, 'Z')
            app.Temperaturprofil_2D_Reaktor_1 = uiaxes(app.AxialerVerlaufTab);
            title(app.Temperaturprofil_2D_Reaktor_1, 'Temperaturprofil 2D-Reaktor')
            xlabel(app.Temperaturprofil_2D_Reaktor_1, '\it{z} / \rm{m}')
            ylabel(app.Temperaturprofil_2D_Reaktor_1, '\it{r}_{Reaktor} / \rm{mm}')
            zlabel(app.Temperaturprofil_2D_Reaktor_1, 'Z')
            app.UmsatzFVEVerlaufTab = uitab(app.TabGroup);
            app.UmsatzFVEVerlaufTab.Title = 'Umsatz & FVE Verlauf';
            app.Umsatz_und_Ausbeute = uiaxes(app.UmsatzFVEVerlaufTab);
            title(app.Umsatz_und_Ausbeute, 'Umsatz und Ausbeute')
            xlabel(app.Umsatz_und_Ausbeute, '\it{z} / \rm{m}')
            ylabel(app.Umsatz_und_Ausbeute, '\it{X}, \it{Y} / %')
            zlabel(app.Umsatz_und_Ausbeute, 'Z')
            app.FVE_Verlauf = uiaxes(app.UmsatzFVEVerlaufTab);
            title(app.FVE_Verlauf, 'FVE Verlauf')
            xlabel(app.FVE_Verlauf, '\it{z} / \rm{m}')
            ylabel(app.FVE_Verlauf, '\it{r}_{Reaktor} / \rm{mm}')
            zlabel(app.FVE_Verlauf, 'Z')
            app.Temperaturprofil_2D_Reaktor_2 = uiaxes(app.UmsatzFVEVerlaufTab);
            title(app.Temperaturprofil_2D_Reaktor_2, 'Temperaturprofil 2D-Reaktor')
            xlabel(app.Temperaturprofil_2D_Reaktor_2, '\it{z} / \rm{m}')
            ylabel(app.Temperaturprofil_2D_Reaktor_2, '\it{r}_{Reaktor} / \rm{mm}')
            zlabel(app.Temperaturprofil_2D_Reaktor_2, 'Z')
            app.StoffmengenanteilePanel = uipanel(app.UIFigure);
            app.StoffmengenanteilePanel.Title = 'Stoffmengenanteile';
            app.rmCH_4EditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmCH_4EditFieldLabel.Interpreter = 'latex';
            app.rmCH_4EditFieldLabel.Text = '$\rm{CH}_{4}$';
            app.CH4EditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.CH4EditField.Value = 0.2128;
            app.rmH_2rmOEditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmH_2rmOEditFieldLabel.Interpreter = 'latex';
            app.rmH_2rmOEditFieldLabel.Text = '$\rm{H}_{2}\rm{O}$';
            app.H2OEditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.H2OEditField.Value = 0.714;
            app.rmH_2EditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmH_2EditFieldLabel.Interpreter = 'latex';
            app.rmH_2EditFieldLabel.Text = '$\rm{H}_{2}$';
            app.H2EditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.H2EditField.Value = 0.0259;
            app.rmCOEditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmCOEditFieldLabel.Interpreter = 'latex';
            app.rmCOEditFieldLabel.Text = '$\rm{CO}$';
            app.COEditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.COEditField.Value = 0.0004;
            app.rmCO_2EditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmCO_2EditFieldLabel.Interpreter = 'latex';
            app.rmCO_2EditFieldLabel.Text = '$\rm{CO}_{2}$';
            app.CO2EditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.CO2EditField.Value = 0.0119;
            app.rmN_2EditFieldLabel = uilabel(app.StoffmengenanteilePanel);
            app.rmN_2EditFieldLabel.Interpreter = 'latex';
            app.rmN_2EditFieldLabel.Text = '$\rm{N}_{2}$';
            app.N2EditField = uieditfield(app.StoffmengenanteilePanel, 'numeric');
            app.N2EditField.Value = 0.035;
            app.ReaktorPanel = uipanel(app.UIFigure);
            app.ReaktorPanel.Title = 'Reaktor';
            app.rmp_rminrmbarLabel = uilabel(app.ReaktorPanel);
            app.rmp_rminrmbarLabel.Interpreter = 'latex';
            app.rmp_rminrmbarLabel.Text = '$\rm{p}_{\rm{in}}\:/\:\rm{bar}$';
            app.p_inEditField = uieditfield(app.ReaktorPanel, 'numeric');
            app.p_inEditField.Value = 25.7;
            app.rmu_inrmms1EditFieldLabel = uilabel(app.ReaktorPanel);
            app.rmu_inrmms1EditFieldLabel.Interpreter = 'latex';
            app.rmu_inrmms1EditFieldLabel.Text = '$\rm{u}_{in}\:/\:\rm{m\,s^{-1}}$';
            app.u_inEditField = uieditfield(app.ReaktorPanel, 'numeric');
            app.u_inEditField.Value = 2.14;
            app.rmT_rminrmKEditFieldLabel = uilabel(app.ReaktorPanel);
            app.rmT_rminrmKEditFieldLabel.Interpreter = 'latex';
            app.rmT_rminrmKEditFieldLabel.Text = '$\rm{T}_{\rm{in}}\:/\:\rm{K}$';
            app.T_inEditField = uieditfield(app.ReaktorPanel, 'numeric');
            app.T_inEditField.Value = 793;
            app.rmT_wallrmKEditFieldLabel = uilabel(app.ReaktorPanel);
            app.rmT_wallrmKEditFieldLabel.Interpreter = 'latex';
            app.rmT_wallrmKEditFieldLabel.Text = '$\rm{T}_{wall}\:/\:\rm{K}$';
            app.T_wallEditField = uieditfield(app.ReaktorPanel, 'numeric');
            app.T_wallEditField.Value = 1100;
            app.rmN_rmelementsLabel = uilabel(app.ReaktorPanel);
            app.rmN_rmelementsLabel.Interpreter = 'latex';
            app.rmN_rmelementsLabel.Text = '$\rm{N}_{\rm{elements}}$';
            app.N_elementsEditField = uieditfield(app.ReaktorPanel, 'numeric');
            app.N_elementsEditField.Value = 10;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Abschlussprojekt_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end